classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MovementSpeedEvaluateBotGaugeLabel  matlab.ui.control.Label
        IgnitionEbotSwitchLabel        matlab.ui.control.Label
        IgnitionQBotSwitchLabel        matlab.ui.control.Label
        StopProgramButton              matlab.ui.control.Button
        MovementSpeedEvaluateBotGauge  matlab.ui.control.LinearGauge
        MovementSpeedQuenchBotGaugeLabel  matlab.ui.control.Label
        PauseProgramButton             matlab.ui.control.Button
        IgnitionEbotSwitch             matlab.ui.control.ToggleSwitch
        IgnitionQBotSwitch             matlab.ui.control.ToggleSwitch
        EmergencyButton                matlab.ui.control.StateButton
        WSlider                        matlab.ui.control.Slider
        WSliderLabel                   matlab.ui.control.Label
        MovementSpeedQuenchBotGauge    matlab.ui.control.LinearGauge
        StartProgramButton             matlab.ui.control.Button
        VSlider                        matlab.ui.control.Slider
        VSliderLabel                   matlab.ui.control.Label
        OperatingLamp                  matlab.ui.control.Lamp
        OperatingLampLabel             matlab.ui.control.Label
        USlider                        matlab.ui.control.Slider
        USliderLabel                   matlab.ui.control.Label
        IdleLamp                       matlab.ui.control.Lamp
        IdleLampLabel                  matlab.ui.control.Label
        ProgramSelectButtonGroup       matlab.ui.container.ButtonGroup
        DisposeButton                  matlab.ui.control.ToggleButton
        EvaluateButton                 matlab.ui.control.ToggleButton
        QuenchButton                   matlab.ui.control.ToggleButton
        PickupButton                   matlab.ui.control.ToggleButton
        MainButton                     matlab.ui.control.ToggleButton
        ZSlider                        matlab.ui.control.Slider
        ZSliderLabel                   matlab.ui.control.Label
        YSlider                        matlab.ui.control.Slider
        YSliderLabel                   matlab.ui.control.Label
        StoppedLamp                    matlab.ui.control.Lamp
        Label                          matlab.ui.control.Label
        AxisControlButtonGroup         matlab.ui.container.ButtonGroup
        EvaluateBotButton              matlab.ui.control.RadioButton
        QuenchBotButton                matlab.ui.control.RadioButton
        XSlider                        matlab.ui.control.Slider
        XSliderLabel                   matlab.ui.control.Label
    end


    properties (Access = private)
        CurrentRobot  % Stores the currently selected robot (Excalibot or UR5)
        robot_excalibot %Excalibot class handler
        robot_UR %UR robot class handler
        robot_UR_maxLimits
        robot_UR_minLimits
        robot_excalibot_maxLimits
        robot_excalibot_minLimits
        gripper
        environment
        selectedProgram
        programRunning
        programStop
        programPause
        holdingSword
        robotMoving
        camera
        temp
        FigureHandle
        tempFigureHandle
        tempAxesHandle
        AxesHandle
        updateCamera
        cameraOffset
    end

    methods (Access = public)

        % Fetch current robot position and update sliders
        function updateSliders(app, robot)
            % Get the current position and rotation of the robot's end effector
            currentPosition = transl(robot.model.fkine(robot.model.getpos()));  % Get translation (x, y, z)
            currentRPY = tr2rpy(robot.model.fkine(robot.model.getpos()));       % Get rotation (roll, pitch, yaw)

            % Define limits for each robot

            if robot == app.robot_excalibot
                minLimits = [-1.9650, -1.3, 0.1];
                maxLimits = [1.2, 1.1, 2.3165];
            elseif robot == app.robot_UR
                minLimits = [-2.2, -1.3, 0.1];
                maxLimits = [-0.3786, 0.3258, 1.6089];
            end

            % Set slider limits based on robot type
            app.XSlider.Limits = [minLimits(1), maxLimits(1)];
            app.YSlider.Limits = [minLimits(2), maxLimits(2)];
            app.ZSlider.Limits = [minLimits(3), maxLimits(3)];

            % Ensure current position values are within the limits
            xValue = min(max(currentPosition(1), minLimits(1)), maxLimits(1));
            yValue = min(max(currentPosition(2), minLimits(2)), maxLimits(2));
            zValue = min(max(currentPosition(3), minLimits(3)), maxLimits(3));

            % Update sliders with constrained position values
            app.XSlider.Value = xValue;  % X-axis
            app.YSlider.Value = yValue;  % Y-axis
            app.ZSlider.Value = zValue;  % Z-axis

            % Convert RPY angles from radians to degrees and constrain them
            rollValue = min(max(rad2deg(currentRPY(1)), -180), 180);
            pitchValue = min(max(rad2deg(currentRPY(2)), -180), 180);
            yawValue = min(max(rad2deg(currentRPY(3)), -180), 180);

            % Update sliders with constrained RPY values
            app.USlider.Value = rollValue;  % Roll
            app.VSlider.Value = pitchValue; % Pitch
            app.WSlider.Value = yawValue;   % Yaw
        end

        % Function to move the robot based on slider values
        function moveRobotBasedOnSliders(app, robot)
            if (~app.robotMoving) && (~app.programRunning)
                % Fetch current slider values for position (X, Y, Z) and rotation (U, V, W)
                newPosition = [app.XSlider.Value, app.YSlider.Value, app.ZSlider.Value];
                newRotation = deg2rad([app.USlider.Value, app.VSlider.Value, app.WSlider.Value]);

                moveRobot(app, robot, newPosition, newRotation, 100);
            end
        end

        function moveRobot(app, robot, desiredPosition, desiredRotation, steps)
            app.robotMoving = true;

            checkForBoundries = true;
            graphTrajectory = true;
            graphBoundryBox = true;

            if isvalid(app.tempAxesHandle) % Only delete if it is valid
                delete(app.tempAxesHandle);
            end

            if robot == app.robot_excalibot
                ignition = app.IgnitionQBotSwitch;
                app.updateCamera = false;
            elseif robot == app.robot_UR
                ignition = app.IgnitionEbotSwitch;
                app.updateCamera = true;
                app.tempAxesHandle = copyobj(app.environment.envAxes, app.tempFigureHandle);
            end

            if ignition.Value == "On" && app.EmergencyButton.Value == false
                [finalJointAngles, finalPosition, finalRotation, positionError, rotationError]  = calculateMotion(app, robot, desiredPosition, desiredRotation);
                % disp("Desired Position");
                % disp(desiredPosition);
                disp("Final Pose");
                disp([finalPosition,finalRotation]);
                % disp("Position Error");
                % disp(positionError);

                trajectory = calculateTrajectory(app, robot, finalJointAngles, steps, checkForBoundries, graphTrajectory, graphBoundryBox);
                animateMotion(app, robot, trajectory, ignition);
            end

            %Ending Operation
            updateSliders(app, robot);
            updateLights(app);

            app.robotMoving = false;
            app.updateCamera = false;  
            
        end

        function [jointAngles, finalPosition, finalRotation, positionError, rotationError] = calculateMotion(app, robot, desiredPosition, desiredRotation)

            % Compute the initial transformation matrix from the desired position and rotation
            newTransformation = transl(desiredPosition) * rpy2tr(desiredRotation);

            % Inverse kinematics with tolerance options
            jointAngles = robot.model.ikcon(newTransformation, 'TolFun', 1e-6, 'TolX', 1e-6);

            % Optionally refine the solution using the IK result
            jointAngles = robot.model.ikcon(newTransformation, jointAngles);

            % Forward kinematics to calculate the resulting end-effector pose
            endEffectorPose = robot.model.fkine(jointAngles);

            % Extract final position and rotation
            finalPosition = transl(endEffectorPose);
            finalRotation = tr2rpy(endEffectorPose);

            % Calculate position error
            positionError = finalPosition - desiredPosition;

            % Calculate rotational error in quaternion form
            endEffectorMatrix = endEffectorPose.T;  % Extract the 4x4 homogeneous transformation matrix
            rotationMatrix = endEffectorMatrix(1:3, 1:3);

            % Convert the rotation matrix to quaternion
            finalQuat = rotm2quat(rotationMatrix);
            desiredQuat = eul2quat(desiredRotation);

            % Compute the quaternion inverse manually
            desiredQuatInv = [desiredQuat(1), -desiredQuat(2), -desiredQuat(3), -desiredQuat(4)];

            % Calculate quaternion multiplication (finalQuat * desiredQuatInv)
            rotationError = quatmultiplyManual(app, finalQuat, desiredQuatInv);
        end

        function trajectory = calculateTrajectory(app, robot, finalJointAngles, steps, checkForBoundries, graphTrajectory, graphBoundryBox)
            % Create Trajectory for the movemement
            trajectory = jtraj(robot.model.getpos(),finalJointAngles, steps);

            if checkForBoundries == true

                % Set the required clearances
                endEffector_clearance_x = 0;
                endEffector_clearance_y = 0;
                endEffector_clearance_z = 0;

                endEffector_offset_x = 0;
                endEffector_offset_y = 0;
                endEffector_offset_z = 0;

                joint_clearance_x = 0;
                joint_clearance_y = 0;
                joint_clearance_z = 0;

                joint_offset_x = 0;
                joint_offset_y = 0;
                joint_offset_z = 0;

                if robot == app.robot_excalibot

                    %Clearance and offset for excalibot
                    endEffector_clearance_x = 0.2;
                    endEffector_clearance_y = 0.2;
                    endEffector_clearance_z = 0.15;

                    endEffector_offset_x = -0.01;
                    endEffector_offset_y = -0.05;
                    endEffector_offset_z = 0.1;

                    joint_clearance_x = 0.2;
                    joint_clearance_y = 0.2;
                    joint_clearance_z = 0.1;

                    joint_offset_x = 0;
                    joint_offset_y = -0.05;
                    joint_offset_z = 0.2;

                elseif robot == app.robot_UR

                    %Clearance and offset for UR5
                    endEffector_clearance_x = 0.05;
                    endEffector_clearance_y = 0.05;
                    endEffector_clearance_z = 0.03;

                    endEffector_offset_x = 0.01;
                    endEffector_offset_y = 0.01;
                    endEffector_offset_z = 0;

                    joint_clearance_x = 0.09;
                    joint_clearance_y = 0.09;
                    joint_clearance_z = 0.03;

                    joint_offset_x = 0;
                    joint_offset_y = 0;
                    joint_offset_z = 0.05;
                end

                % Calculate all end effector positions
                % Initialize a cell array to store end-effector poses (transformation matrices)
                endEffectorPoses = cell(size(trajectory, 1), 1);
                endEffectorPositions = cell(size(trajectory, 1), 1);
                endEffectorRotations = cell(size(trajectory, 1), 1);

                % Initialize arrays to store joint positions throughout the trajectory
                numJoints = length(robot.model.links);
                jointPositions = zeros(size(trajectory, 1), numJoints + 1, 3); % [step, joint+base, x/y/z]

                for i = 1:size(trajectory, 1)
                    % get current joint state
                    q = trajectory(i, :);

                    % Extrack coordinates of end effector
                    endEffectorPose = robot.model.fkine(q);
                    endEffectorPosition = transl(endEffectorPose);
                    endEffectorRotation = tr2rpy(endEffectorPose);


                    % Calculate the transformation matrix for each joint at step i
                    T = robot.model.base; % Start with the base transformation
                    jointPositions(i, 1, :) = T.t; % Store base position

                    for j = 1:numJoints
                        T = T * robot.model.A(j, q); % Multiply by the transformation of each joint
                        jointPositions(i, j+1, :) = T.t; % Store the position of the joint
                    end


                    % save the coordinates of end effector
                    endEffectorPoses{i} = endEffectorPose;
                    endEffectorPositions{i} = endEffectorPosition;
                    endEffectorRotations{i} = endEffectorRotation;
                end

                % Extract end effector x, y, z
                xPos = zeros(size(endEffectorPositions, 1), 1);
                yPos = zeros(size(endEffectorPositions, 1), 1);
                zPos = zeros(size(endEffectorPositions, 1), 1);
                for i = 1:size(endEffectorPositions, 1)
                    position = endEffectorPositions(i);
                    xyzPos = position{1};
                    xPos(i) = xyzPos(1);
                    yPos(i) = xyzPos(2);
                    zPos(i) = xyzPos(3);
                end

                % Find the minimum and maximum values for x, y, and z end effector positions
                minX_endEffector = (min(xPos) - endEffector_clearance_x) + endEffector_offset_x;
                maxX_endEffector = (max(xPos) + endEffector_clearance_x) + endEffector_offset_x;

                minY_endEffector = (min(yPos) - endEffector_clearance_y) + endEffector_offset_y;
                maxY_endEffector = (max(yPos) + endEffector_clearance_y) + endEffector_offset_y;

                minZ_endEffector = (min(zPos) - endEffector_clearance_z) + endEffector_offset_z;
                maxZ_endEffector = (max(zPos) + endEffector_clearance_z) + endEffector_offset_z;

                % find the minimum and maximum values for x, y, and z for
                % total robot
                minX_total = (min(jointPositions(:, :, 1), [], 'all') - joint_clearance_x) + joint_offset_x;
                maxX_total = (max(jointPositions(:, :, 1), [], 'all') + joint_clearance_x) + joint_offset_x;

                minY_total = (min(jointPositions(:, :, 2), [], 'all') - joint_clearance_y) + joint_offset_y;
                maxY_total = (max(jointPositions(:, :, 2), [], 'all') + joint_clearance_y) + joint_offset_y;

                minZ_total = (min(jointPositions(:, :, 3), [], 'all') - joint_clearance_z) + joint_offset_z;
                maxZ_total = (max(jointPositions(:, :, 3), [], 'all') + joint_clearance_z) + joint_offset_z;

                % Define persistent variables to store plot handles
                persistent trajHandle boxHandles totalBoundryHandles

                % Check and delete the previous trajectory and bounding box if they exist
                if ~isempty(trajHandle)
                    delete(trajHandle);  % Delete the old trajectory plot
                end
                if ~isempty(boxHandles)
                    delete(boxHandles);  % Delete the old end effector bounding box plot (both corners and edges)
                end
                if ~isempty(totalBoundryHandles)
                    delete(totalBoundryHandles);  % Delete the old total bounding box plot (both corners and edges)
                end

                % Plot the new bounding box if needed
                if graphBoundryBox == true

                    % Define the 8 corners of the total bounding box
                    corners_total = [
                        minX_total, minY_total, minZ_total;  % Corner 1 (min x, min y, min z)
                        maxX_total, minY_total, minZ_total;  % Corner 2 (max x, min y, min z)
                        maxX_total, maxY_total, minZ_total;  % Corner 3 (max x, max y, min z)
                        minX_total, maxY_total, minZ_total;  % Corner 4 (min x, max y, min z)
                        minX_total, minY_total, maxZ_total;  % Corner 5 (min x, min y, max z)
                        maxX_total, minY_total, maxZ_total;  % Corner 6 (max x, min y, max z)
                        maxX_total, maxY_total, maxZ_total;  % Corner 7 (max x, max y, max z)
                        minX_total, maxY_total, maxZ_total   % Corner 8 (min x, max y, max z)
                        ];

                    % Define the 8 corners of the end effector bounding box
                    corners_endEffector = [
                        minX_endEffector, minY_endEffector, minZ_endEffector;  % Corner 1 (min x, min y, min z)
                        maxX_endEffector, minY_endEffector, minZ_endEffector;  % Corner 2 (max x, min y, min z)
                        maxX_endEffector, maxY_endEffector, minZ_endEffector;  % Corner 3 (max x, max y, min z)
                        minX_endEffector, maxY_endEffector, minZ_endEffector;  % Corner 4 (min x, max y, min z)
                        minX_endEffector, minY_endEffector, maxZ_endEffector;  % Corner 5 (min x, min y, max z)
                        maxX_endEffector, minY_endEffector, maxZ_endEffector;  % Corner 6 (max x, min y, max z)
                        maxX_endEffector, maxY_endEffector, maxZ_endEffector;  % Corner 7 (max x, max y, max z)
                        minX_endEffector, maxY_endEffector, maxZ_endEffector   % Corner 8 (min x, max y, max z)
                        ];

                    % Define the edges of the box (each row corresponds to a pair of corners to connect)
                    edges = [
                        1, 2;  % Connect corner 1 to 2
                        2, 3;  % Connect corner 2 to 3
                        3, 4;  % Connect corner 3 to 4
                        4, 1;  % Connect corner 4 to 1 (base of the box)
                        5, 6;  % Connect corner 5 to 6
                        6, 7;  % Connect corner 6 to 7
                        7, 8;  % Connect corner 7 to 8
                        8, 5;  % Connect corner 8 to 5 (top of the box)
                        1, 5;  % Connect corner 1 to 5
                        2, 6;  % Connect corner 2 to 6
                        3, 7;  % Connect corner 3 to 7
                        4, 8   % Connect corner 4 to 8
                        ];


                    % Plot the corners of the total bounding box
                    totalBoundryHandles = gobjects(size(corners_total, 1) + size(edges, 1), 1);  % Preallocate handles
                    totalBoundryHandles(1:size(corners_total, 1)) = plot3(corners_total(:, 1), corners_total(:, 2), corners_total(:, 3), ...
                        'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'y','Parent',app.environment.envAxes);  % Red markers for corners
                    hold on;

                    % Plot the edges of the total box and store the handles
                    for i = 1:size(edges, 1)
                        p1 = corners_total(edges(i, 1), :);  % First point of the edge
                        p2 = corners_total(edges(i, 2), :);  % Second point of the edge
                        totalBoundryHandles(size(corners_total, 1) + i) = line([p1(1), p2(1)], [p1(2), p2(2)], [p1(3), p2(3)], ...
                            'Color', 'y', 'LineWidth', 2,'Parent',app.environment.envAxes);  % Plot the edge as a blue line
                    end

                    % Plot the corners of the end effector bounding box
                    boxHandles = gobjects(size(corners_endEffector, 1) + size(edges, 1), 1);  % Preallocate handles
                    boxHandles(1:size(corners_endEffector, 1)) = plot3(corners_endEffector(:, 1), corners_endEffector(:, 2), corners_endEffector(:, 3), ...
                        'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'b','Parent',app.environment.envAxes);  % Red markers for corners
                    hold on;

                    % Plot the edges of the end effector box and store the handles
                    for i = 1:size(edges, 1)
                        p1 = corners_endEffector(edges(i, 1), :);  % First point of the edge
                        p2 = corners_endEffector(edges(i, 2), :);  % Second point of the edge
                        boxHandles(size(corners_endEffector, 1) + i) = line([p1(1), p2(1)], [p1(2), p2(2)], [p1(3), p2(3)], ...
                            'Color', 'b', 'LineWidth', 2,'Parent',app.environment.envAxes);  % Plot the edge as a blue line
                    end
                end

                % Plot the new trajectory if needed
                if graphTrajectory == true
                    % Plot the new trajectory and store its handle
                    for i = 1:length(yPos)
                        if yPos(i) <= 1.15 && xPos(i) <= 1.25 && yPos(i) >= -1.35 && xPos(i) >= -2.25 && zPos(i) >= 0.05
                            % Plot points with y < 1.2 in green
                            trajHandle = [trajHandle, plot3(xPos(i), yPos(i), zPos(i), 'go', 'LineWidth', 2, 'MarkerSize', 5,'Parent',app.environment.envAxes)];
                        else
                            % Plot points with y >= 1.2 in red
                            trajHandle = [trajHandle, plot3(xPos(i), yPos(i), zPos(i), 'ro', 'LineWidth', 2, 'MarkerSize', 5,'Parent',app.environment.envAxes)];
                        end
                    end
                    hold on;
                end
            end
        end

        function animateMotion(app, robot, trajectory, ignition)
            % Enable operating lamp and disable idle/stopped lamps
            app.OperatingLamp.Enable = "on";
            app.IdleLamp.Enable = "off";
            app.StoppedLamp.Enable = "off";
            swordTracking = false;

            % Update the appropriate speed gauge based on robot type
            if robot == app.robot_UR
                gauge = app.MovementSpeedEvaluateBotGauge;
            elseif robot == app.robot_excalibot
                gauge = app.MovementSpeedQuenchBotGauge;
                if app.holdingSword
                    swordTracking = true;
                end
            end

            % Initialize previous position
            previousPosition = transl(robot.model.fkine(trajectory(1, :)));

            % Animate motion
            for i = 1:size(trajectory, 1)
                % Get current end-effector position
                endEffectorPose = robot.model.fkine(trajectory(i, :));
                endEffectorPosition = transl(endEffectorPose);

                % Check boundary and ignition conditions
                if ~(endEffectorPosition(2) < 1.1 && endEffectorPosition(1) < 1.2 && endEffectorPosition(2) > -1.3 && endEffectorPosition(1) > -2.2 && endEffectorPosition(3) > 0.1)
                    break;
                end
                if ~(ignition.Value == "On" && app.EmergencyButton.Value == false)
                    break;
                end

                % Calculate speed (distance between current and previous position)
                speed = (norm(endEffectorPosition - previousPosition))*1000;

                gauge.Value = speed;


                % Animate robot and update sliders
                robot.model.animate(trajectory(i, :));

                if swordTracking
                    robot.UpdateSwordMesh(app.environment.sword.patch,app.environment.sword.vertices);
                end
                if app.updateCamera

                    T = endEffectorPose.T;
                    R = T(1:3, 1:3);
                    app.camera = app.camera.setPosition(endEffectorPosition+(R * app.cameraOffset)'); 
                    app.camera = app.camera.setOrientation(R*rotx(deg2rad(90))*roty(deg2rad(0))*rotz(deg2rad(0)));

                    app.camera.captureAndDisplay(app.AxesHandle,app.tempAxesHandle,app.environment.envAxes);
                end
                

                updateSliders(app, robot);
                drawnow();

                % Update previous position for next iteration
                previousPosition = endEffectorPosition;
            end

            % Final update of sliders and draw
            updateSliders(app, robot);

            % Update the appropriate speed gauge based on robot type
            gauge.Value = 0;

            drawnow();
        end

        function [reachablePoints, accuratePoints, minXYZ, maxXYZ, accurateMinXYZ, accurateMaxXYZ] = findReachablePoints(app, robot, xRange, yRange, zRange, resolution)
            % Initialize arrays to store reachable points
            reachablePoints = [];
            accuratePoints = [];

            % Create a mesh grid of points in the specified ranges
            [X, Y, Z] = meshgrid(xRange(1):resolution:xRange(2), ...
                yRange(1):resolution:yRange(2), ...
                zRange(1):resolution:zRange(2));

            % Iterate through the grid of points
            for i = 1:numel(X)
                % Define current desired position
                desiredPosition = [X(i), Y(i), Z(i)];
                disp(desiredPosition);

                % Set the desired rotation to zero (neutral orientation), you can modify this as needed
                desiredRotation = [0, 0, 0];  % roll, pitch, yaw


                [jointAngles, finalPosition, finalRotation, positionError, rotationError] = calculateMotion(app, robot, desiredPosition, desiredRotation);

                % If the position error is small, consider the point reachable
                reachablePoints = [reachablePoints; finalPosition];  % Add the reachable point

                if positionError < 0.01
                    accuratePoints = [accuratePoints; finalPosition];  % Add the reachable point
                end


            end

            % Calculate the minimum and maximum x, y, z coordinates of the reachable points
            if ~isempty(reachablePoints)
                minXYZ = min(reachablePoints, [], 1);  % [minX, minY, minZ]
                maxXYZ = max(reachablePoints, [], 1);  % [maxX, maxY, maxZ]
            else
                % If no points are reachable, return empty arrays
                minXYZ = [];
                maxXYZ = [];
            end

            if ~isempty(accuratePoints)
                accurateMinXYZ = min(accuratePoints, [], 1);  % [minX, minY, minZ]
                accurateMaxXYZ = max(accuratePoints, [], 1);  % [maxX, maxY, maxZ]
            else
                % If no points are reachable, return empty arrays
                accurateMinXYZ = [];
                accurateMaxXYZ = [];
            end
        end

        function result = quatmultiplyManual(app, q1, q2)
            % Quaternion multiplication: q1 * q2
            w1 = q1(1); x1 = q1(2); y1 = q1(3); z1 = q1(4);
            w2 = q2(1); x2 = q2(2); y2 = q2(3); z2 = q2(4);

            % Quaternion multiplication formula
            result = [
                w1*w2 - x1*x2 - y1*y2 - z1*z2, ...
                w1*x2 + x1*w2 + y1*z2 - z1*y2, ...
                w1*y2 + y1*w2 + z1*x2 - x1*z2, ...
                w1*z2 + z1*w2 + x1*y2 - y1*x2
                ];
        end

        function MoveRobotToSword(app)

            app.AxisControlButtonGroup.SelectedObject = app.QuenchBotButton;
            updateSliders(app, app.robot_excalibot);  % Update sliders based on Excalibot's position
            app.CurrentRobot = app.robot_excalibot;  % Store the selected robot
            updateLights(app);

            % Define waypoints for the robot
            first_point = [1.121, 0.52165, 0.82457, 0, 0, 0];  % Above the sword
            second_point = [1.121, 0.52165, 0.7, 0, 0, 0];  % Down to the sword
            third_point = first_point;  % Back up to the first point
            fourth_point = [0.836, -0.16782, 0.9, 0, 0, 0];  % Move to another location
            fifth_point = [0.836, -0.65, 0.9, 0, 0, 0];  % Final location

            % Move to the first point (above the sword)
            disp('Moving to the first point (above the sword)...');
            moveRobot(app,app.robot_excalibot,first_point(1:3),first_point(4:6),100);

            % Move down to the second point (on the sword)
            disp('Moving down to the second point (on the sword)...');
            moveRobot(app,app.robot_excalibot,second_point(1:3),second_point(4:6),100);
            
            pause(0.5);  % Wait for 1 second to simulate gripping the sword
            app.holdingSword = true;

            % Enable sword tracking and move back up
            disp('Gripping the sword and moving back up...');
            moveRobot(app,app.robot_excalibot,third_point(1:3),third_point(4:6),100);

            % Move to the third point
            disp('Moving to the third point...');
            moveRobot(app,app.robot_excalibot,fourth_point(1:3),fourth_point(4:6),100);

            % Move to the final point
            disp('Moving to the final point...');
            moveRobot(app,app.robot_excalibot,fifth_point(1:3),fifth_point(4:6),100);            disp('Sword Heating Up');
        end

        function MoveToWater(app)

            app.AxisControlButtonGroup.SelectedObject = app.QuenchBotButton;
            updateSliders(app, app.robot_excalibot);  % Update sliders based on Excalibot's position
            app.CurrentRobot = app.robot_excalibot;  % Store the selected robot
            updateLights(app);

            % Define waypoints for the robot
            first_point = [0.836, 0.1, 0.9, 0, 0, 0];  % First point (above the water bucket)
            second_point =  [0.4, -0.4, 1.5, 0, 0, 0];
            third_point = [0, -0.8, 1.5,  0, 0, 0];  % Down to the water bucket
            rotating_point1 = [0, -0.8, 1.5,  1.57, 0, 1.57];
            rotating_point2 = [0, -0.8, 1, 1.57, -0, 1.57];
            fourth_point = [0, -0.8, 0.7, 1.57, 0, 1.57];   % Final position in the water bucket

            % Move to the first point (above the water bucket)
            disp('Moving to the first point (above the water bucket)...');
            moveRobot(app,app.robot_excalibot,first_point(1:3),first_point(4:6),100);            % Wait for half a second

            % Move down to the second point (near the water bucket)
            disp('Moving down to the second point (near the water bucket)...');
            %moveRobot(app,app.robot_excalibot,second_point(1:3),second_point(4:6),100);
            % Move to the third point (final position in the water bucket)
            disp('Moving to the third point (in the water bucket)...');
            %moveRobot(app,app.robot_excalibot,third_point(1:3),third_point(4:6),100);
            moveRobot(app,app.robot_excalibot,rotating_point1(1:3),rotating_point1(4:6),100);
            %moveRobot(app,app.robot_excalibot,rotating_point2(1:3),rotating_point2(4:6),100);
             pause(0.5);  % Wait for half a second

            %    % Move to the third point (final position in the water bucket)
            disp('Moving to the third point (in the water bucket)...');
            moveRobot(app,app.robot_excalibot,fourth_point(1:3),fourth_point(4:6),100);
        end

        function MoveToInspection(app)

            app.AxisControlButtonGroup.SelectedObject = app.QuenchBotButton;
            updateSliders(app, app.robot_excalibot);  % Update sliders based on Excalibot's position
            app.CurrentRobot = app.robot_excalibot;  % Store the selected robot
            updateLights(app);

            % Define waypoints for the robot
            first_point = [0, -0.8, 1.5, 1.57, -0, 1.57];
            second_point =  [-0.75, -0.459, 0.831, 0, -0, 0];


            % Move to the first point (above the water bucket)
            disp('Moving to the first point (above the water bucket)...');
            moveRobot(app,app.robot_excalibot,first_point(1:3),first_point(4:6),100);            % Wait for half a second

            % Move down to the second point (near the water bucket)
            disp('Moving down to the second point (near the water bucket)...');
            moveRobot(app,app.robot_excalibot,second_point(1:3),second_point(4:6),100);
        end

        function MoveCOBOTtoInspection(app)

            app.AxisControlButtonGroup.SelectedObject = app.EvaluateBotButton;
            updateSliders(app, app.robot_UR);  % Update sliders based on UR5's position
            app.CurrentRobot = app.robot_UR;  % Store the selected robot
            updateLights(app);
            
            % Define the waypoints for the robot in 3D space
            %first_point = [originalPosition(1), originalPosition(2), 0.941, 0, pi, 0];  % First point in space (intermediate)
            second_point = [-0.75, -0.62, 0.941, 0, pi, 0];  % First point in space (intermediate)
            third_point = [-0.76, -1.05, 0.941, 0, pi, 0];   % Second point in space (final)
            endscan_point = [-1.7, -0.5, 1.4, 0, 0, 0];
            %home_point = [originalPosition, oryginalRotation];

            %moveRobot(app,app.robot_UR,first_point(1:3),first_point(4:6),100);
            % Animate the robot from its starting position to the first point
            disp('Moving to the first point with downward orientation...');
            moveRobot(app,app.robot_UR,second_point(1:3),second_point(4:6),100);

            disp('Slowly moving to the second point with downward orientation...');
            moveRobot(app,app.robot_UR,third_point(1:3),third_point(4:6),100);

            disp('Returning to the original position...');
            moveRobot(app,app.robot_UR,endscan_point(1:3),endscan_point(4:6),100);
            %moveRobot(app,app.robot_UR,home_point(1:3),home_point(4:6),100);

            disp('Returned to the original position.');
        end

        function MoveToDropOff(app)

            app.AxisControlButtonGroup.SelectedObject = app.QuenchBotButton;
            updateSliders(app, app.robot_excalibot);  % Update sliders based on Excalibot's position
            app.CurrentRobot = app.robot_excalibot;  % Store the selected robot
            updateLights(app);

            % Define waypoints for the robot
            first_point = [-1.1456, 0.98152, 0.9, 0, 0, 0];
            second_point =  [-1.1456, 0.98152, 0.7, 0, 0, -pi/2];
            third_point =  [-1.1456, 0.98152, 0.7, 0, 0, 0];
            Final_point =  [0.7474, 0.2926, 1.2722, 0, 0, 0];


            % Move to the first point
            disp('Moving to the dropoff...');
            moveRobot(app,app.robot_excalibot,first_point(1:3),first_point(4:6),100);
            % Wait for half a second

            % Move down to the second point (near
            disp('dropping off');
            moveRobot(app,app.robot_excalibot,second_point(1:3),second_point(4:6),100);
            app.holdingSword = false;
            moveRobot(app,app.robot_excalibot,third_point(1:3),third_point(4:6),100);
            moveRobot(app,app.robot_excalibot,Final_point(1:3),Final_point(4:6),100);
        end

        function MainProgram(app)

            MoveRobotToSword(app);

            MoveToWater(app);

            MoveToInspection(app);

            MoveCOBOTtoInspection(app);

            MoveToDropOff(app);
        end
    end

    methods (Access = private)

        function updateLights(app)
            if app.EmergencyButton.Value == true
                app.OperatingLamp.Enable = "off";
                app.IdleLamp.Enable = "off";
                app.StoppedLamp.Enable ="on";
            else
                %Adjust Lamps QBot
                if app.IgnitionQBotSwitch.Value == "On"
                    if app.CurrentRobot == app.robot_excalibot
                        app.OperatingLamp.Enable = "off";
                        app.IdleLamp.Enable = "on";
                        app.StoppedLamp.Enable ="off";
                    end
                end

                if app.IgnitionQBotSwitch.Value == "Off"
                    if app.CurrentRobot == app.robot_excalibot
                        app.OperatingLamp.Enable = "off";
                        app.IdleLamp.Enable = "off";
                        app.StoppedLamp.Enable ="on";
                    end
                end
                %Adjust Lamps EBot
                if app.IgnitionEbotSwitch.Value == "On"
                    if app.CurrentRobot == app.robot_UR
                        app.OperatingLamp.Enable = "off";
                        app.IdleLamp.Enable = "on";
                        app.StoppedLamp.Enable ="off";
                    end
                end

                if app.IgnitionEbotSwitch.Value == "Off"
                    if app.CurrentRobot == app.robot_UR
                        app.OperatingLamp.Enable = "off";
                        app.IdleLamp.Enable = "off";
                        app.StoppedLamp.Enable ="on";
                    end
                end
            end
        end

        function createBoundryShapes(app)
            %%%% Find reachable points for robots %%%%%
            % Define the workspace boundaries and resolution
            xRange = [-5, 5];  % X limits in meters
            yRange = [-5, 5];  % Y limits in meters
            zRange = [0.1, 5];   % Z limits in meters
            resolution = 0.2;  % 10 cm resolution

            % Call the function to find reachable Exclaibot points
            [reachablePointsExcalibot,accuratePointsExcalibot, app.robot_excalibot_minLimits, app.robot_excalibot_maxLimits,accurateMinXYZexcalibot,accurateMaxXYZexcalibot] = findReachablePoints(app, app.robot_excalibot, xRange, yRange, zRange, resolution);

            if isempty(reachablePointsExcalibot)
                disp('No reachable points to plot.');
                return;
            end

            % Use alphaShape to create a mesh representing the volume of reachable points
            shpExcalibot = alphaShape(reachablePointsExcalibot(:,1), reachablePointsExcalibot(:,2), reachablePointsExcalibot(:,3), resolution); % 1 is the alpha radius, adjust as needed
            accurateShpExcalibot = alphaShape(accuratePointsExcalibot(:,1), accuratePointsExcalibot(:,2), accuratePointsExcalibot(:,3), resolution);

            % Plot the alpha shape
            shpExcalibotplot = plot(shpExcalibot);
            accurateShpExcalibotPlot = plot(accurateShpExcalibot);
            % Set the color and transparency properties
            shpExcalibotplot.FaceColor = [0.8, 0.4, 0.4];     % Change 'red' to any color you like or use an RGB triplet, e.g., [0.5, 0.8, 0.2]
            shpExcalibotplot.EdgeColor = 'none';    % 'none' hides the edges, or you can set it to a specific color
            shpExcalibotplot.FaceAlpha = 0.5;       % Set transparency (0 = fully transparent, 1 = fully opaque)

            % Set the color and transparency properties
            accurateShpExcalibotPlot.FaceColor = 'red';     % Change 'red' to any color you like or use an RGB triplet, e.g., [0.5, 0.8, 0.2]
            accurateShpExcalibotPlot.EdgeColor = 'none';    % 'none' hides the edges, or you can set it to a specific color
            accurateShpExcalibotPlot.FaceAlpha = 0.5;       % Set transparency (0 = fully transparent, 1 = fully opaque)


            xRange = [-5, 5];  % X limits in meters
            yRange = [-5, 5];  % Y limits in meters
            zRange = [0.1, 5];   % Z limits in meters
            resolution = 0.2;  % 5 cm resolution

            % Call the function to find reachable URponts
            [reachablePointsUR,accuratePointsUR, app.robot_UR_minLimits, app.robot_UR_maxLimits,accurateMinXYZur,accurateMaxXYZur] = findReachablePoints(app, app.robot_UR, xRange, yRange, zRange, resolution);

            if isempty(reachablePointsUR)
                disp('No reachable points to plot.');
                return;
            end

            % Use alphaShape to create a mesh representing the volume of reachable points
            shpUR = alphaShape(reachablePointsUR(:,1), reachablePointsUR(:,2), reachablePointsUR(:,3), resolution); % 1 is the alpha radius, adjust as needed
            accurateShpUR = alphaShape(accuratePointsUR(:,1), accuratePointsUR(:,2), accuratePointsUR(:,3), resolution); % 1 is the alpha radius, adjust as needed

            % Plot the alpha shape
            shpURplot = plot(shpUR);
            accurateShpURplot = plot(accurateShpUR);
            % Set the color and transparency properties
            shpURplot.FaceColor = [0.4, 0.7, 0.4];     % Change 'red' to any color you like or use an RGB triplet, e.g., [0.5, 0.8, 0.2]
            shpURplot.EdgeColor = 'none';    % 'none' hides the edges, or you can set it to a specific color
            shpURplot.FaceAlpha = 0.5;       % Set transparency (0 = fully transparent, 1 = fully opaque)

            % Set the color and transparency properties
            accurateShpURplot.FaceColor = 'green';     % Change 'red' to any color you like or use an RGB triplet, e.g., [0.5, 0.8, 0.2]
            accurateShpURplot.EdgeColor = 'none';    % 'none' hides the edges, or you can set it to a specific color
            accurateShpURplot.FaceAlpha = 0.5;       % Set transparency (0 = fully transparent, 1 = fully opaque)

            disp("MinExcalibot");
            disp(app.robot_excalibot_minLimits);

            disp ("MaxExcalibot");
            disp(app.robot_excalibot_maxLimits);

            disp("AccurateMinExcalibot");
            disp(accurateMinXYZexcalibot);

            disp ("AccurateMaxExcalibot");
            disp(accurateMaxXYZexcalibot);

            disp("MinUR");
            disp(app.robot_UR_minLimits);

            disp ("MaxUR");
            disp(app.robot_UR_maxLimits);

            disp("AccurateMinUR");
            disp(accurateMinXYZur);

            disp ("AccurateMaxUR");
            disp(accurateMaxXYZur);

            save("reachablePointsExcalibot_far.mat","reachablePointsExcalibot");
            save("reachablePointsUR_far.mat","reachablePointsUR");
            save("AccuratePointsExcalibot_far.mat","accuratePointsExcalibot");
            save("AccuratePointsUR_far.mat","accuratePointsUR");

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%% System Start    %%
            %%%%%%%%%%%%%%%%%%%%%%%%%%
            startup_rvc;
            clc; close all;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%   Initialize Environment  %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.environment = Environment;
            
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%  Excalibot   %%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%Select Starting Position Excalibot%%%%%%%%%
            % translation part
            position_Excalibot = [-0.06748, 0.3819, 0]; % x, y, z coordinates

            % rotation part
            roll_Excalibot = deg2rad(0); % Roll (rotation about x-axis)
            pitch_Excalibot = deg2rad(0); % Pitch (rotation about y-axis)
            yaw_Excalibot = deg2rad(180); % Yaw (rotation about z-axis)

            % transformation matrix using transl and rpy2tr
            baseTr_Excalibot = transl(position_Excalibot) * rpy2tr(roll_Excalibot, pitch_Excalibot, yaw_Excalibot);

            %%%%%%%%%% Create an instance of the Excalibot class %%%%%%%%
            app.robot_excalibot = Excalibot(baseTr_Excalibot);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%    UR  %%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%Select Starting Position UR%%%%%%%%%
            % translation part
            position_UR = [-1.3134, -0.61995, 0.58111]; % x, y, z coordinates

            % rotation part
            roll_UR = deg2rad(0); % Roll (rotation about x-axis)
            pitch_UR = deg2rad(0); % Pitch (rotation about y-axis)
            yaw_UR = deg2rad(180); % Yaw (rotation about z-axis)

            % transformation matrix using transl and rpy2tr
            baseTr_UR = transl(position_UR) * rpy2tr(roll_UR, pitch_UR, yaw_UR);

            %%%%%%%%%% Create an instance of the URx class %%%%%%%%
            app.robot_UR = UR5(baseTr_UR);

            %%%%Excalibot starting pose %%%%%
            [startintgJointAngleExcalibot, ~, ~, ~, ~] = calculateMotion(app, app.robot_excalibot, [0.7788,0.2714,1.3423], [0,0,0]);
            app.robot_excalibot.model.animate(startintgJointAngleExcalibot);
            drawnow();

            %%Ur5 starting position%%
            [startintgJointAngleUR, ~, ~, ~, ~] = calculateMotion(app, app.robot_UR, [-1.7,-0.4999,1.4], [0,0,0]);
            % startintgJointAngleUR = startintgJointAngleUR +
            % [-pi/2,0,0,0,0,0];
            app.robot_UR.model.animate(startintgJointAngleUR);
            drawnow();

            %%%%%%%%Initialize slider control%%%%%%%%%%
            % Set initial robot as Excalibot
            app.CurrentRobot = app.robot_excalibot;

            % Initialize sliders with Excalibot's position
            updateSliders(app, app.CurrentRobot);

            %%%%Initialize Lights%%%%%%
            updateLights(app);

            %%% Program Option
            app.selectedProgram = "Main";
            app.programStop = false;
            app.programPause = false;
            app.programRunning = false;
            app.holdingSword = false;
            app.robotMoving = false;
            app.updateCamera = false;

            Ur5Pose = app.robot_UR.model.fkine(app.robot_UR.model.getpos());
            app.camera = Camera(app.environment, [1, 1, 1], eye(3));
            app.cameraOffset = [0; 0; 0.1];

            app.tempFigureHandle = figure('Visible', 'off');
            app.tempAxesHandle = copyobj(app.environment.envAxes, app.tempFigureHandle);

            app.FigureHandle = figure;
            app.AxesHandle = axes('Parent', app.FigureHandle);
            T = Ur5Pose.T;
            R = T(1:3, 1:3);
            app.camera = app.camera.setPosition(transl(Ur5Pose)+(R * app.cameraOffset)');
            app.camera = app.camera.setOrientation(R*rotx(deg2rad(90))*roty(deg2rad(0))*rotz(deg2rad(0)));
            app.camera.captureAndDisplay(app.AxesHandle,app.tempAxesHandle,app.environment.envAxes);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clc; close all;
        end

        % Value changed function: IgnitionQBotSwitch
        function IgnitionQBotSwitchValueChanged(app, event)
            updateLights(app);
        end

        % Value changed function: IgnitionEbotSwitch
        function IgnitionEbotSwitchValueChanged(app, event)
            updateLights(app);
        end

        % Selection changed function: AxisControlButtonGroup
        function AxisControlButtonGroupSelectionChanged(app, event)
            selectedButton = app.AxisControlButtonGroup.SelectedObject;

            if selectedButton == app.QuenchBotButton
                % QuenchBot (Excalibot) selected
                updateSliders(app, app.robot_excalibot);  % Update sliders based on Excalibot's position
                app.CurrentRobot = app.robot_excalibot;  % Store the selected robot
                updateLights(app);
            elseif selectedButton == app.EvaluateBotButton
                % EvaluateBot (UR5) selected
                updateSliders(app, app.robot_UR);  % Update sliders based on UR5's position
                app.CurrentRobot = app.robot_UR;  % Store the selected robot
                updateLights(app);
            end

        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: USlider
        function USliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: VSlider
        function VSliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: WSlider
        function WSliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: EmergencyButton
        function EmergencyButtonValueChanged(app, event)
            updateLights(app);
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;  % Get the key that was pressed

            % Check if the pressed key is 'space'
            if strcmp(key, 'space')
                app.EmergencyButton.Value = ~app.EmergencyButton.Value;
                updateLights(app);
            end

        end

        % Selection changed function: ProgramSelectButtonGroup
        function ProgramSelectButtonGroupSelectionChanged(app, event)
            app.selectedProgram = app.ProgramSelectButtonGroup.SelectedObject.Text;

        end

        % Button pushed function: StartProgramButton
        function StartProgramButtonPushed(app, event)
            app.programPause = false;
            
            if (~app.programRunning) && (~app.robotMoving)
                app.programRunning = true;
                switch app.selectedProgram
                    case "Main"
                        MainProgram(app);
                        app.programRunning = false;
                    case "Pickup"
                        MoveRobotToSword(app);
                        app.programRunning = false;
                    case "Quench"
                        MoveToWater(app);
                        app.programRunning = false;
                    case "Evaluate"
                        MoveToInspection(app);
                        MoveCOBOTtoInspection(app);
                        app.programRunning = false;
                    case "Dispose"
                        MoveToDropOff(app);
                        app.programRunning = false;
                end
            end
        end

        % Button pushed function: PauseProgramButton
        function PauseProgramButtonPushed(app, event)
            app.programPause = true;
        end

        % Button pushed function: StopProgramButton
        function StopProgramButtonPushed(app, event)
            app.programStop = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [-5 5];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.AxisControlButtonGroup = uibuttongroup(app.UIFigure);
            app.AxisControlButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AxisControlButtonGroupSelectionChanged, true);
            app.AxisControlButtonGroup.Title = 'AxisControl';
            app.QuenchBotButton = uiradiobutton(app.AxisControlButtonGroup);
            app.QuenchBotButton.Text = 'QuenchBot';
            app.QuenchBotButton.Value = true;
            app.EvaluateBotButton = uiradiobutton(app.AxisControlButtonGroup);
            app.EvaluateBotButton.Text = 'EvaluateBot';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Stopped';
            app.StoppedLamp = uilamp(app.UIFigure);
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [-5 5];
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [-5 5];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ProgramSelectButtonGroup = uibuttongroup(app.UIFigure);
            app.ProgramSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProgramSelectButtonGroupSelectionChanged, true);
            app.ProgramSelectButtonGroup.Title = 'ProgramSelect';
            app.MainButton = uitogglebutton(app.ProgramSelectButtonGroup);
            app.MainButton.Text = 'Main';
            app.MainButton.Value = true;
            app.PickupButton = uitogglebutton(app.ProgramSelectButtonGroup);
            app.PickupButton.Text = 'Pickup';
            app.QuenchButton = uitogglebutton(app.ProgramSelectButtonGroup);
            app.QuenchButton.Text = 'Quench';
            app.EvaluateButton = uitogglebutton(app.ProgramSelectButtonGroup);
            app.EvaluateButton.Text = 'Evaluate';
            app.DisposeButton = uitogglebutton(app.ProgramSelectButtonGroup);
            app.DisposeButton.Text = 'Dispose';
            app.IdleLampLabel = uilabel(app.UIFigure);
            app.IdleLampLabel.Text = 'Idle';
            app.IdleLamp = uilamp(app.UIFigure);
            app.USliderLabel = uilabel(app.UIFigure);
            app.USliderLabel.Text = 'U';
            app.USlider = uislider(app.UIFigure);
            app.USlider.Limits = [-180 180];
            app.USlider.ValueChangedFcn = createCallbackFcn(app, @USliderValueChanged, true);
            app.OperatingLampLabel = uilabel(app.UIFigure);
            app.OperatingLampLabel.Text = 'Operating';
            app.OperatingLamp = uilamp(app.UIFigure);
            app.VSliderLabel = uilabel(app.UIFigure);
            app.VSliderLabel.Text = 'V';
            app.VSlider = uislider(app.UIFigure);
            app.VSlider.Limits = [-180 180];
            app.VSlider.ValueChangedFcn = createCallbackFcn(app, @VSliderValueChanged, true);
            app.StartProgramButton = uibutton(app.UIFigure, 'push');
            app.StartProgramButton.ButtonPushedFcn = createCallbackFcn(app, @StartProgramButtonPushed, true);
            app.StartProgramButton.Text = 'StartProgram';
            app.MovementSpeedQuenchBotGauge = uigauge(app.UIFigure, 'linear');
            app.WSliderLabel = uilabel(app.UIFigure);
            app.WSliderLabel.Text = 'W';
            app.WSlider = uislider(app.UIFigure);
            app.WSlider.Limits = [-180 180];
            app.WSlider.ValueChangedFcn = createCallbackFcn(app, @WSliderValueChanged, true);
            app.EmergencyButton = uibutton(app.UIFigure, 'state');
            app.EmergencyButton.ValueChangedFcn = createCallbackFcn(app, @EmergencyButtonValueChanged, true);
            app.EmergencyButton.Icon = fullfile(pathToMLAPP, 'emergency-stop-warning-sign-s-1182.png');
            app.EmergencyButton.Text = '';
            app.IgnitionQBotSwitch = uiswitch(app.UIFigure, 'toggle');
            app.IgnitionQBotSwitch.ValueChangedFcn = createCallbackFcn(app, @IgnitionQBotSwitchValueChanged, true);
            app.IgnitionEbotSwitch = uiswitch(app.UIFigure, 'toggle');
            app.IgnitionEbotSwitch.ValueChangedFcn = createCallbackFcn(app, @IgnitionEbotSwitchValueChanged, true);
            app.PauseProgramButton = uibutton(app.UIFigure, 'push');
            app.PauseProgramButton.ButtonPushedFcn = createCallbackFcn(app, @PauseProgramButtonPushed, true);
            app.PauseProgramButton.Text = 'PauseProgram';
            app.MovementSpeedQuenchBotGaugeLabel = uilabel(app.UIFigure);
            app.MovementSpeedQuenchBotGaugeLabel.Text = 'MovementSpeedQuenchBot';
            app.MovementSpeedEvaluateBotGauge = uigauge(app.UIFigure, 'linear');
            app.StopProgramButton = uibutton(app.UIFigure, 'push');
            app.StopProgramButton.ButtonPushedFcn = createCallbackFcn(app, @StopProgramButtonPushed, true);
            app.StopProgramButton.Text = 'StopProgram';
            app.IgnitionQBotSwitchLabel = uilabel(app.UIFigure);
            app.IgnitionQBotSwitchLabel.Text = 'IgnitionQBot';
            app.IgnitionEbotSwitchLabel = uilabel(app.UIFigure);
            app.IgnitionEbotSwitchLabel.Text = 'IgnitionEbot';
            app.MovementSpeedEvaluateBotGaugeLabel = uilabel(app.UIFigure);
            app.MovementSpeedEvaluateBotGaugeLabel.Text = 'MovementSpeedEvaluateBot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end